classdef GENIST_regressiontreepipeline_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        GENISTTab                       matlab.ui.container.Tab
        GENIST_InfoTextArea             matlab.ui.control.TextArea
        GENIST_RunPanel                 matlab.ui.container.Panel
        GENIST_RunButton                matlab.ui.control.Button
        GENIST_StatusField              matlab.ui.control.TextArea
        GENIST_InputsInfoPanel          matlab.ui.container.Panel
        GENIST_InfoListBox              matlab.ui.control.ListBox
        GENIST_InputsInfoTextArea       matlab.ui.control.TextArea
        GENIST_OptionalInputsPanel      matlab.ui.container.Panel
        GENIST_LoadNewDataLabel         matlab.ui.control.Label
        GENIST_LoadNewDataCheckBox      matlab.ui.control.CheckBox
        GENIST_TFFileButton             matlab.ui.control.Button
        GENIST_SymbolFileButton         matlab.ui.control.Button
        TranscriptionFactorsFileEditFieldLabel  matlab.ui.control.Label
        GENIST_TranscriptionFactorsFileEditField  matlab.ui.control.EditField
        TimeLapseDropDownLabel          matlab.ui.control.Label
        GENIST_TimeLapseDropDown        matlab.ui.control.DropDown
        SymbolFileEditFieldLabel        matlab.ui.control.Label
        GENIST_SymbolFileEditField      matlab.ui.control.EditField
        RegFoldChangeThresholdEditFieldLabel  matlab.ui.control.Label
        GENIST_RegFoldChangeThresholdEditField  matlab.ui.control.NumericEditField
        RegTimePercentEditFieldLabel    matlab.ui.control.Label
        GENIST_RegTimePercentEditField  matlab.ui.control.NumericEditField
        NLevelsEditFieldLabel           matlab.ui.control.Label
        GENIST_NLevelsEditField         matlab.ui.control.NumericEditField
        RegBottomPercentageEditFieldLabel  matlab.ui.control.Label
        GENIST_RegBottomPercentageEditField  matlab.ui.control.NumericEditField
        GENIST_RequiredInputsPanel      matlab.ui.container.Panel
        GENSIT_GenesFileButton          matlab.ui.control.Button
        GENIST_TimeCourseFileButton     matlab.ui.control.Button
        GENIST_ClusteringFileButton     matlab.ui.control.Button
        TimeCourseFileEditFieldLabel    matlab.ui.control.Label
        GENIST_TimeCourseFileEditField  matlab.ui.control.EditField
        GenesFileEditFieldLabel         matlab.ui.control.Label
        GENIST_GenesFileEditField       matlab.ui.control.EditField
        ClusteringDataFileEditFieldLabel  matlab.ui.control.Label
        GENIST_ClusteringFileEditField  matlab.ui.control.EditField
        RegressionTreePipelineTab       matlab.ui.container.Tab
        RTP_InfoTextArea                matlab.ui.control.TextArea
        RTP_TimeCourseOptionsPanel      matlab.ui.container.Panel
        NumberofTimePointsifapplicableEditFieldLabel  matlab.ui.control.Label
        RTP_TimePointsEditField         matlab.ui.control.NumericEditField
        FoldChangeCutoffifapplicableEditFieldLabel  matlab.ui.control.Label
        RTP_ThresholdEditField          matlab.ui.control.NumericEditField
        RTP_UseTimeCourseLabel          matlab.ui.control.Label
        RTP_UseTimeCourseCheckBox       matlab.ui.control.CheckBox
        RTP_ClusteringOptionsPanel      matlab.ui.container.Panel
        SetSeedforClusteringLabel       matlab.ui.control.Label
        RTP_ClusteringSeedEditField     matlab.ui.control.NumericEditField
        MaxNumberofClustersLabel        matlab.ui.control.Label
        RTP_MaxClustersEditField        matlab.ui.control.NumericEditField
        RTP_ConnectHubsLabel            matlab.ui.control.Label
        RTP_ConnectHubsCheckBox         matlab.ui.control.CheckBox
        RTP_UseClusteringLabel          matlab.ui.control.Label
        RTP_UseClusteringCheckBox       matlab.ui.control.CheckBox
        RTP_NotClustersLabel            matlab.ui.control.Label
        ClusteringFileNamecsvortxtLabel  matlab.ui.control.Label
        RTP_NotClustersFileNameEditField  matlab.ui.control.EditField
        RTP_EdgeNumberThresholdsPanel   matlab.ui.container.Panel
        LowNumberofTranscriptionFactorsEditFieldLabel  matlab.ui.control.Label
        RTP_LowThresholdEditField       matlab.ui.control.NumericEditField
        HighNumberofTranscriptionFactorsEditFieldLabel  matlab.ui.control.Label
        RTP_HighThresholdEditField      matlab.ui.control.NumericEditField
        MediumNumberofTranscriptionFactorsEditFieldLabel  matlab.ui.control.Label
        RTP_MediumThresholdEditField    matlab.ui.control.NumericEditField
        RTP_InputFilesPanel             matlab.ui.container.Panel
        RTP_GeneExpressionFileButton    matlab.ui.control.Button
        RTP_GeneSymbolsFileButton       matlab.ui.control.Button
        RTP_ClusteringFileButton        matlab.ui.control.Button
        ResultsFileNameEditFieldLabel   matlab.ui.control.Label
        RTP_ResultsFileNameEditField    matlab.ui.control.EditField
        GeneSymbolsFileEditFieldLabel   matlab.ui.control.Label
        RTP_GeneSymbolsFileEditField    matlab.ui.control.EditField
        GeneExpressionFileEditFieldLabel  matlab.ui.control.Label
        RTP_GeneExpressionFileEditField  matlab.ui.control.EditField
        ClusteringFileOptionalLabel     matlab.ui.control.Label
        RTP_ClusteringFileEditField     matlab.ui.control.EditField
        RTP_InputsInfoPanel             matlab.ui.container.Panel
        RTP_InfoListBox                 matlab.ui.control.ListBox
        RTP_InputsInfoTextArea          matlab.ui.control.TextArea
        RTP_RunPanel                    matlab.ui.container.Panel
        RTP_RunButton                   matlab.ui.control.Button
        RTP_StatusField                 matlab.ui.control.TextArea
    end


    methods (Access = private)

        % Button pushed function: GENIST_ClusteringFileButton
        function GENIST_ClusteringFileButtonPushed(app, event)
            [ClusteringFile, ClusteringFilePathname] = uigetfile('.xlsx'); % Prompt user to choose file via file explorer
            if(not(ClusteringFile==0))
                app.GENIST_ClusteringFileEditField.Value = strcat(ClusteringFilePathname,ClusteringFile); % Set edit field to choosen file name
            end
            app.UIFigure.Visible = 'off'; % Reopen GUI
            app.UIFigure.Visible = 'on';
        end

        % Value changed function: GENIST_InfoListBox
        function GENIST_InfoListBoxValueChanged(app, event)
            listBoxValue = app.GENIST_InfoListBox.Value; % Change info text box based on user selection
            if(listBoxValue=="0")
                app.GENIST_InputsInfoTextArea.Value = ['Genes File must be an excel file containing '...
                    'the list of genes to be included in the network as a column.'];
            elseif(listBoxValue=="1")
                app.GENIST_InputsInfoTextArea.Value = ['Time Course File must be an excel file '...
                    'containing expression data of the genes across different time or developmental '...
                    'time points. Each row must correspond to 1 gene. The file can contain '...
                    'any number of genes, and GENIST selects the genes specified in the genes file.'];
            elseif(listBoxValue=="2")
                app.GENIST_InputsInfoTextArea.Value = ['Clustering Data File must be an excel '... 
                    'file containing expression data of the genes across different '...
                    'spacial confinements. Each row must correspond to 1 gene. The '...
                    'file can contain any number of genes, and GENIST selects the '...
                    'genes specified in the genes file. The names of the genes in all '...
                    '.xlsx files must be consistent.'];
            elseif(listBoxValue=="3")
                app.GENIST_InputsInfoTextArea.Value = ['Time Lapse indicates the lapse between '...
                    'the activation of genes that the algorithm will consider to '...
                    'select the regulators of a gene. If time lapse is 1, GENIST '...
                    'assumes that genes that are active in one time point can regulate '...
                    'other genes in the following time point. '...
                    'If time lapse is 0, GENIST assumes that genes that are active in one '...
                    'time point can regulate other genes in the same time point. '...
                    'If time lapse is [0,1], GENIST assumes that genes that are active in one '...
                    'time point can regulate other genes in the same time point or in the '...
                    'following time point. Default is 1.'];
            elseif(listBoxValue=="4")
                app.GENIST_InputsInfoTextArea.Value = ['If Load New Data is checked, all data '...
                    'is loaded and saved into Matlab. '...
                    'If load new data is unchecked, the data last saved is used to run GENIST. '...
                    'Default is checked.'];
            elseif(listBoxValue=="5")
                app.GENIST_InputsInfoTextArea.Value = ['Transcription Factors File must be an excel file '...
                    'containing a list of transcription factors as a column '...
                    'vector. If transcription factors file is provided, only the genes '...
                    '(from the genes file) that '...
                    'are also included in the transcription factors file will be considered '...
                    'as potential regulators in the network.'];
            elseif(listBoxValue=="6")
                app.GENIST_InputsInfoTextArea.Value = ['Symbol File must be an excel file '...
                    'containing a list of genes in the '...
                    'first column, and a corresponding symbol for each gene in the second '...
                    'column. When symbol file is provided, the genes in the network for '...
                    'which a symbol was provided are represented by their symbol. '...
                    'The names of the genes in all .xlsx files must be consistent.'];
            elseif(listBoxValue=="7")
                app.GENIST_InputsInfoTextArea.Value = ['Reg Fold Change Threshold is the minimum (threshold) '...
                    'fold-change expression that a  '...
                    'gene must experience between two consecutive time points for it to be '...
                    'considered a potential regulator of another gene that is changing  '...
                    'expression in the same '...
                    '(time lapse = 0) or the next (time lapse = 1) time point. Default is 1.3.'];
            elseif(listBoxValue=="8")
                app.GENIST_InputsInfoTextArea.Value = ['Reg Time Percent is the minimum percentage '...
                    'of the total time points '...
                    'that a potential regulator and a target show simultaneous '...
                    '(if time lapse = 0) or consecutive (if time lapse = 1) changes of '...
                    'expression for a gene to be considered a potential regulator of the '...
                    'target gene. Default is 0.3.'];
            elseif(listBoxValue=="9")
                app.GENIST_InputsInfoTextArea.Value = ['N Levels is the number of levels in '...
                    'which the time course expression data '...
                    'will be discretized for calculating the probabilities that each potential '...
                    'regulator regulates each target. Must be an integer. Default is 2. '...
                    'Minimum allowed value is 2. Recommended values 2 or 3.'];
            elseif(listBoxValue=="10")
                app.GENIST_InputsInfoTextArea.Value = ['Reg Bottom Percentage sets the bottom percentage '...
                    'of regulations of the final '...
                    'network that will be considered too low to be displayed (will be '...
                    'discarded). Default is 0.2.'];
            end
        end

        % Button pushed function: GENIST_RunButton
        function GENIST_RunButtonPushed(app, event)
            tf1 = strcmp(app.GENIST_GenesFileEditField.Value,''); % Check if file name inputs have been provided
            tf2 = strcmp(app.GENIST_TimeCourseFileEditField.Value,'');
            tf3 = strcmp(app.GENIST_ClusteringFileEditField.Value,'');
            tf4 = strcmp(app.GENIST_TranscriptionFactorsFileEditField.Value,'');
            tf5 = strcmp(app.GENIST_SymbolFileEditField.Value,'');
            if(tf1||tf2||tf3) % Error if required inputs have not been provided
                message = 'Please provide all required inputs: genes, time course, and clustering files.';
                uialert(app.UIFigure,message,'Error','Icon','error');
            else % Run if required inputs have been provided
                app.GENIST_StatusField.Value = '';
                % Get required inputs
                genes_file = app.GENIST_GenesFileEditField.Value;
                time_course_file = app.GENIST_TimeCourseFileEditField.Value;
                clustering_data_file = app.GENIST_ClusteringFileEditField.Value;
                if(exist(genes_file,'file') == 0) % Check if given files exist
                    message = 'Provided genes file cannot be found.';
                    uialert(app.UIFigure,message,'Error','Icon','error');
                    return;
                end
                if(exist(time_course_file,'file') == 0) % Check if given files exist
                    message = 'Provided time course file cannot be found.';
                    uialert(app.UIFigure,message,'Error','Icon','error');
                    return;
                end
                if(exist(clustering_data_file,'file') == 0) % Check if given files exist
                    message = 'Provided clustering data file cannot be found.';
                    uialert(app.UIFigure,message,'Error','Icon','error');
                    return;
                end
                [~,~,ext] = fileparts(genes_file); % Check if provided files are Excel files
                if(strcmp(ext,'.xlsx')==0)
                    message = 'Provided genes file must be of type *.xlsx.';
                    uialert(app.UIFigure,message,'Error','Icon','error');
                    return;
                end
                [~,~,ext] = fileparts(time_course_file); % Check if provided files are Excel files
                if(strcmp(ext,'.xlsx')==0)
                    message = 'Provided time course file must be of type *.xlsx.';
                    uialert(app.UIFigure,message,'Error','Icon','error');
                    return;
                end
                [~,~,ext] = fileparts(clustering_data_file); % Check if provided files are Excel files
                if(strcmp(ext,'.xlsx')==0)
                    message = 'Provided clustering data file must be of type *.xlsx.';
                    uialert(app.UIFigure,message,'Error','Icon','error');
                    return;
                end
                % Get optional inputs
                if(app.GENIST_TimeLapseDropDown.Value=='1')
                    time_lapse = 1;
                elseif(app.GENIST_TimeLapseDropDown.Value=='0')
                    time_lapse = 0;
                else
                    time_lapse = [0,1];
                end
                is_load_new_data = app.GENIST_LoadNewDataCheckBox.Value;
                is_reg_fc_th = app.GENIST_RegFoldChangeThresholdEditField.Value;
                is_reg_time_percent = app.GENIST_RegTimePercentEditField.Value;
                n_levels = app.GENIST_NLevelsEditField.Value;
                is_low_conn = app.GENIST_RegBottomPercentageEditField.Value;
                if(tf4) % Check if user has input optional file
                    TF_file = [];
                else
                    TF_file = app.GENIST_TranscriptionFactorsFileEditField.Value;
                    if(exist(TF_file,'file') == 0) % Check if given files exist
                        message = 'Provided transcription factor file cannot be found.';
                        uialert(app.UIFigure,message,'Error','Icon','error');
                        return;
                    end
                    [~,~,ext] = fileparts(TF_file); % Check if provided files are Excel files
                    if(strcmp(ext,'.xlsx')==0)
                        message = 'Provided transcription factors file must be of type *.xlsx.';
                        uialert(app.UIFigure,message,'Error','Icon','error');
                        return;
                    end
                end
                if(tf5) % Check if user has input optional file
                    symbol_file = [];
                else
                    symbol_file = app.GENIST_SymbolFileEditField.Value;
                    if(exist(symbol_file,'file') == 0) % Check if given files exist
                        message = 'Provided symbol file cannot be found.';
                        uialert(app.UIFigure,message,'Error','Icon','error');
                        return;
                    end
                    [~,~,ext] = fileparts(symbol_file); % Check if provided files are Excel files
                    if(strcmp(ext,'.xlsx')==0)
                        message = 'Provided symbol file must be of type *.xlsx.';
                        uialert(app.UIFigure,message,'Error','Icon','error');
                        return;
                    end
                end
                % Run algorithm using inputs
                warning off
                app.GENIST_StatusField.Value = 'Working...';
                try % Try/catch block to catch errors during algorithm runtime
                    GENIST2_0(genes_file,time_course_file,clustering_data_file,time_lapse,...
                    is_load_new_data,TF_file,symbol_file,is_reg_fc_th,...
                    is_reg_time_percent,n_levels,is_low_conn);
                catch ME % Display error info (ID,message,stack) if an error occurs
                    uialert(app.UIFigure,getReport(ME,'extended','hyperlinks','off'),'Error Info','Icon','error');
                    app.GENIST_StatusField.Value = 'Error';
                    error_message = ['An error occurred and the algorithm was unable to finish running. '...
                        'Please ensure that all input data files are correctly '...
                        'formatted and that the correct input data files have been selected.'];
                    uialert(app.UIFigure,error_message,'Runtime Error','Icon','error');
                    return;
                end
                app.GENIST_StatusField.Value = 'Done';
                success_message = ['Results can be found in cytoscape_table.txt. This file is located in '...
                    'the current working Matlab directory.'];
                uialert(app.UIFigure,success_message,'Success','Icon','success');
            end
        end

        % Button pushed function: GENIST_SymbolFileButton
        function GENIST_SymbolFileButtonPushed(app, event)
            [SymbolFile,SymbolFilePathname] = uigetfile('.xlsx'); % Prompt user to choose file via file explorer
            if(not(SymbolFile==0))
                app.GENIST_SymbolFileEditField.Value = strcat(SymbolFilePathname,SymbolFile); % Set edit field to choosen file name
            end
            app.UIFigure.Visible = 'off'; % Reopen GUI
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: GENIST_TFFileButton
        function GENIST_TFFileButtonPushed(app, event)
            [TFFile,TFFilePathname] = uigetfile('.xlsx'); % Prompt user to choose file via file explorer
            if(not(TFFile==0))
                app.GENIST_TranscriptionFactorsFileEditField.Value = strcat(TFFilePathname,TFFile); % Set edit field to choosen file name
            end
            app.UIFigure.Visible = 'off'; % Reopen GUI
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: GENIST_TimeCourseFileButton
        function GENIST_TimeCourseFileButtonPushed(app, event)
            [TimeCourseFile,TimeCourseFilePathname] = uigetfile('.xlsx'); % Prompt user to choose file via file explorer
            if(not(TimeCourseFile==0))
                app.GENIST_TimeCourseFileEditField.Value = strcat(TimeCourseFilePathname,TimeCourseFile); % Set edit field to choosen file name
            end
            app.UIFigure.Visible = 'off'; % Reopen GUI
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: GENSIT_GenesFileButton
        function GENSIT_GenesFileButtonPushed(app, event)
            [GenesFile, GenesFilePathname] = uigetfile('.xlsx'); % Prompt user to choose file via file explorer
            if(not(GenesFile==0))
                app.GENIST_GenesFileEditField.Value = strcat(GenesFilePathname,GenesFile); % Set edit field to choosen file name
            end
            app.UIFigure.Visible = 'off'; % Reopen GUI
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: RTP_ClusteringFileButton
        function RTP_ClusteringFileButtonButtonPushed(app, event)
            [ClusteringFile, ClusteringFilePathname] = uigetfile('*.mat'); % Prompt user to choose file via file explorer
            if(not(ClusteringFile==0))
                app.RTP_ClusteringFileEditField.Value = strcat(ClusteringFilePathname,ClusteringFile);
            end
            app.UIFigure.Visible = 'off'; % Reopen GUI
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: RTP_GeneExpressionFileButton
        function RTP_GeneExpressionFileButtonButtonPushed(app, event)
            [GeneExpressionFile,GeneExpressionFilePathname] = uigetfile('*.mat'); % Prompt user to choose file via file explorer
            if(not(GeneExpressionFile==0))
                app.RTP_GeneExpressionFileEditField.Value = strcat(GeneExpressionFilePathname,GeneExpressionFile);
            end
            app.UIFigure.Visible = 'off'; % Reopen GUI
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: RTP_GeneSymbolsFileButton
        function RTP_GeneSymbolsFileButtonButtonPushed(app, event)
            [SymbolsFile,SymbolsFilePathname] = uigetfile('*.mat'); % Prompt user to choose file via file explorer
            if(not(SymbolsFile==0))
                app.RTP_GeneSymbolsFileEditField.Value = strcat(SymbolsFilePathname,SymbolsFile);
            end
            app.UIFigure.Visible = 'off'; % Reopen GUI
            app.UIFigure.Visible = 'on';
        end

        % Value changed function: RTP_InfoListBox
        function RTP_InfoListBoxValueChanged(app, event)
            value = app.RTP_InfoListBox.Value; % Change info text box based on user selection
            if(value=="0")
                app.RTP_InputsInfoTextArea.Value = ['Gene Expression File is the Matlab table '...
                    'that contains the expression data being used for GRN '...
                    'inference.'];
            elseif(value=="1")
                app.RTP_InputsInfoTextArea.Value = ['Gene Symbols File is the Matlab table '...
                    'that includes the known symbols for some genes.'];
            elseif(value=="2")
                app.RTP_InputsInfoTextArea.Value = ['Clustering File is the Matlab table '...
                    'of predetermined clusters if you are clustering this '...
                    'data. This is NOT required. The default is that this '...
                    'is not provided and Regression Tree Pipeline will '...
                    'perform the clustering.'];
            elseif(value=="3")
                app.RTP_InputsInfoTextArea.Value = ['Results File Name is the name of '....
                    'the file(s) where you want to write results. '...
                    'Default name is biograph.txt. If clustering, files '...
                    'will be indexed based on cluster number. If clustering, '...
                    'your final network will be your chosen filename+final.txt.'];
            elseif(value=="4")
                app.RTP_InputsInfoTextArea.Value = ['Use Time Course indicates that '...
                    'you will be using Regression Tree Pipeline to '...
                    'analyze time course data. The default is for '...
                    'this to be selected and to use time course data.'];
            elseif(value=="5")
                app.RTP_InputsInfoTextArea.Value = ['Number of Time Points indicates '...
                    'how many observations are in your time course data. '...
                    'The default value is 12. The default value will be '...
                    'displayed but ignored if you are analyzing data '...
                    'from replicates.'];
            elseif(value=="6")
                app.RTP_InputsInfoTextArea.Value = ['Fold Change Cutoff indicates '...
                    'the threshold to use if using time course data '...
                    'for directionality.'];
            elseif(value=="7")
                app.RTP_InputsInfoTextArea.Value = ['Use Clustering indicates '...
                    'that you are using clustering before inferring '...
                    'networks. The default is to use clustering. '...
                    'Leave this box checked if you are providing '...
                    'predetermined clustering data.'];
            elseif(value=="8")
                app.RTP_InputsInfoTextArea.Value = ['Uploaded Clustering File indicates '...
                    'that you uploaded predetermined clustering data. '...
                    'The default is for this to be deselected and allow '...
                    'Regression Tree Pipeline to perform the clustering.'];
            elseif(value=="9")
                app.RTP_InputsInfoTextArea.Value = ['Connect Hubs indicates whether to '...
                    'connect the hubs of each cluster. The default is '...
                    'selected and will allow the hubs to be connected.'];
            elseif(value=="10")
                app.RTP_InputsInfoTextArea.Value = ['Set Seed for Clustering allows '...
                    'you to set the seed for clustering. The default '...
                    'is not to set a seed and allow Regression Tree '...
                    'Pipeline to assign one randomly. Input "0" to assign seed randomly.'];
            elseif(value=="11")
                app.RTP_InputsInfoTextArea.Value = ['Max Number of Clusters allows '...
                    'you to set the maximum number of clusters. The '...
                    'default is p/10 + 5, where p is the number of genes. '...
                    'Input "0" to use default value.'];
            elseif(value=="12")
                app.RTP_InputsInfoTextArea.Value = ['Clustering File Name allows '...
                    'you to choose the name of the clustering file '...
                    'if you are allowing Regression Tree Pipeline '...
                    'to perform the clustering. The extension must '...
                    'be specified. The dault is clusters.csv.'];
            elseif(value=="13")
                app.RTP_InputsInfoTextArea.Value = ['Low Number of Transcription Factors allows '...
                    'you to set the probability threshold for showing an '...
                    'edge when there is a low number of transcription factors.'];
            elseif(value=="14")
                app.RTP_InputsInfoTextArea.Value = ['Medium Number of Transcription Factors allows '...
                    'you to set the probability threshold for showing an '...
                    'edge when there is a medium number of transcription factors.'];
            elseif(value=="15")
                app.RTP_InputsInfoTextArea.Value = ['High Number of Transcription Factors allows '...
                    'you to set the probability threshold for showing an '...
                    'edge when there is a large number of transcription factors.'];
            end
        end

        % Button pushed function: RTP_RunButton
        function RTP_RunButtonPushed(app, event)
            expression_data = app.RTP_GeneExpressionFileEditField.Value; % Input Files
            clustering_data = app.RTP_ClusteringFileEditField.Value;
            symbol = app.RTP_GeneSymbolsFileEditField.Value;
            filename_results = app.RTP_ResultsFileNameEditField.Value;
            if(strcmp(expression_data,'')) % Check if filename has been provided
                message = 'Please provide a gene expression file.';
                uialert(app.UIFigure,message,'Error','Icon','error');
                return;
            end
            if(strcmp(symbol,'')) % Check if filename has been provided
                message = 'Please provide a gene symbols file.';
                uialert(app.UIFigure,message,'Error','Icon','error');
                return;
            end
            if(exist(expression_data,'file') == 0) % Check if given files exist
                    message = 'Provided gene expression file cannot be found.';
                    uialert(app.UIFigure,message,'Error','Icon','error');
                    return;
            end
            if(exist(symbol,'file') == 0) % Check if given files exist
                    message = 'Provided gene symbol file cannot be found.';
                    uialert(app.UIFigure,message,'Error','Icon','error');
                    return;
            end
            if(strcmp(filename_results,'')) % Check if filename has been provided
                message = 'Please provide a results file name.';
                uialert(app.UIFigure,message,'Error','Icon','error');
                return;
            end
            if(strcmp(clustering_data,'') == 0) % Check if optional file is given
                if(exist(clustering_data,'file') == 0) % Check if optional file exists
                    message = 'Provided clustering file cannot be found.';
                    uialert(app.UIFigure,message,'Error','Icon','error');
                    return;
                end
                usepresetclusters = 0;
            else
                usepresetclusters = 1;
            end
            [~,~,ext] = fileparts(expression_data); % Check if provided files are .mat files
            if(strcmp(ext,'.mat')==0)
                message = 'Provided gene expression file must be of type *.mat.';
                uialert(app.UIFigure,message,'Error','Icon','error');
                return;
            end
            [~,~,ext] = fileparts(symbol); % Check if provided files are .mat files
            if(strcmp(ext,'.mat')==0)
                message = 'Provided gene symbol file must be of type *.mat.';
                uialert(app.UIFigure,message,'Error','Icon','error');
                return;
            end
            [~,~,ext] = fileparts(clustering_data); % Check if provided files are .mat files
            if(strcmp(ext,'.mat')==0)
                message = 'Provided clustering file must be of type *.mat.';
                uialert(app.UIFigure,message,'Error','Icon','error');
                return;
            end
            istimecourse = app.RTP_UseTimeCourseCheckBox.Value;
            timecols = app.RTP_TimePointsEditField.Value;
            timethreshold = app.RTP_ThresholdEditField.Value;
            isclustering = app.RTP_UseClusteringCheckBox.Value;
            if(app.ClusterSeedOption.Value==0)
                clusteringseed = [];
            else
                clusteringseed = app.RTP_ClusteringSeedEditField.Value;
            end
            if(app.RTP_MaxClustersEditField.Value==0)
                maxclusters = [];
            else
                maxclusters = app.MaxnumberClustersOption.Value;
            end
            filename_cluster = app.RTP_NotClustersFileNameEditField.Value;
            connecthubs = app.RTP_ConnectHubsCheckBox.Value;
            edgenumber = [app.RTP_LowThresholdEditField.Value app.RTP_MediumThresholdEditField.Value app.RTP_HighThresholdEditField.Value];
            % Run algorithm using inputs
            app.RTP_StatusField.Value = 'Working...';
            regression_tree_pipeline(expression_data, clustering_data, symbol, ... % Run algorithm
                connecthubs, clusteringseed, isclustering, usepresetclusters, ...
                istimecourse, maxclusters, filename_cluster, timecols, ...
                timethreshold, edgenumber, filename_results);
            app.RTP_StatusField.Value = 'Done';
            message = ['Results can be found in ' + filename_results + '. This file is '...
                'located in the current working Matlab directory.'];
            uialert(app.UIFigure,message,'Success','Icon','success');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Gene Regulations GUI';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GENISTTab = uitab(app.TabGroup);
            app.GENISTTab.Title = 'GENIST';
            app.GENIST_InfoTextArea = uitextarea(app.GENISTTab);
            app.GENIST_InfoTextArea.Editable = 'off';
            app.GENIST_InfoTextArea.Value = {'The GENIST algorithm infers regulations among provided genes. The regulations are calculated by applying a first step of clustering that groups the genes based on their coexpression in the Clustering Data File (which should be preferently a spatial dataset) followed by a second step of a Bayesian Network inference, which uses the data in the Time Course File. This algorithm requires a time course input. For inferring regulation without a time course, use the Regression Tree Pipeline algorithm.'};
            app.GENIST_RunPanel = uipanel(app.GENISTTab);
            app.GENIST_RunButton = uibutton(app.GENIST_RunPanel, 'push');
            app.GENIST_RunButton.ButtonPushedFcn = createCallbackFcn(app, @GENIST_RunButtonPushed, true);
            app.GENIST_RunButton.Text = 'Run';
            app.GENIST_StatusField = uitextarea(app.GENIST_RunPanel);
            app.GENIST_StatusField.Editable = 'off';
            app.GENIST_InputsInfoPanel = uipanel(app.GENISTTab);
            app.GENIST_InputsInfoPanel.Title = 'Inputs Info';
            app.GENIST_InfoListBox = uilistbox(app.GENIST_InputsInfoPanel);
            app.GENIST_InfoListBox.Items = {'Genes File', 'Time Course File', 'Clustering Data File', 'Time Lapse', 'Load New Data', 'Transcription Factors File', 'Symbol File', 'Reg Fold Change Threshold', 'Reg Time Percent', 'N Levels', 'Reg Bottom Percentage'};
            app.GENIST_InfoListBox.ItemsData = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.GENIST_InfoListBox.ValueChangedFcn = createCallbackFcn(app, @GENIST_InfoListBoxValueChanged, true);
            app.GENIST_InfoListBox.Value = '0';
            app.GENIST_InputsInfoTextArea = uitextarea(app.GENIST_InputsInfoPanel);
            app.GENIST_InputsInfoTextArea.Editable = 'off';
            app.GENIST_InputsInfoTextArea.Value = {'Genes File must be an excel file containing the list of genes to be included in the network as a column.'};
            app.GENIST_OptionalInputsPanel = uipanel(app.GENISTTab);
            app.GENIST_OptionalInputsPanel.Title = 'Optional Inputs';
            app.GENIST_LoadNewDataLabel = uilabel(app.GENIST_OptionalInputsPanel);
            app.GENIST_LoadNewDataLabel.Text = 'Load New Data';
            app.GENIST_LoadNewDataCheckBox = uicheckbox(app.GENIST_OptionalInputsPanel);
            app.GENIST_LoadNewDataCheckBox.Text = '';
            app.GENIST_LoadNewDataCheckBox.Value = true;
            app.GENIST_TFFileButton = uibutton(app.GENIST_OptionalInputsPanel, 'push');
            app.GENIST_TFFileButton.ButtonPushedFcn = createCallbackFcn(app, @GENIST_TFFileButtonPushed, true);
            app.GENIST_TFFileButton.Text = 'Choose File';
            app.GENIST_SymbolFileButton = uibutton(app.GENIST_OptionalInputsPanel, 'push');
            app.GENIST_SymbolFileButton.ButtonPushedFcn = createCallbackFcn(app, @GENIST_SymbolFileButtonPushed, true);
            app.GENIST_SymbolFileButton.Text = 'Choose File';
            app.TranscriptionFactorsFileEditFieldLabel = uilabel(app.GENIST_OptionalInputsPanel);
            app.TranscriptionFactorsFileEditFieldLabel.Text = 'Transcription Factors File';
            app.GENIST_TranscriptionFactorsFileEditField = uieditfield(app.GENIST_OptionalInputsPanel, 'text');
            app.TimeLapseDropDownLabel = uilabel(app.GENIST_OptionalInputsPanel);
            app.TimeLapseDropDownLabel.Text = 'Time Lapse';
            app.GENIST_TimeLapseDropDown = uidropdown(app.GENIST_OptionalInputsPanel);
            app.GENIST_TimeLapseDropDown.Items = {'1', '0', '[0,1]'};
            app.GENIST_TimeLapseDropDown.Value = '1';
            app.SymbolFileEditFieldLabel = uilabel(app.GENIST_OptionalInputsPanel);
            app.SymbolFileEditFieldLabel.Text = 'Symbol File';
            app.GENIST_SymbolFileEditField = uieditfield(app.GENIST_OptionalInputsPanel, 'text');
            app.RegFoldChangeThresholdEditFieldLabel = uilabel(app.GENIST_OptionalInputsPanel);
            app.RegFoldChangeThresholdEditFieldLabel.Text = 'Reg Fold Change Threshold';
            app.GENIST_RegFoldChangeThresholdEditField = uieditfield(app.GENIST_OptionalInputsPanel, 'numeric');
            app.GENIST_RegFoldChangeThresholdEditField.Limits = [0 Inf];
            app.GENIST_RegFoldChangeThresholdEditField.Value = 1.3;
            app.RegTimePercentEditFieldLabel = uilabel(app.GENIST_OptionalInputsPanel);
            app.RegTimePercentEditFieldLabel.Text = 'Reg Time Percent';
            app.GENIST_RegTimePercentEditField = uieditfield(app.GENIST_OptionalInputsPanel, 'numeric');
            app.GENIST_RegTimePercentEditField.Limits = [0 1];
            app.GENIST_RegTimePercentEditField.Value = 0.3;
            app.NLevelsEditFieldLabel = uilabel(app.GENIST_OptionalInputsPanel);
            app.NLevelsEditFieldLabel.Text = 'N Levels';
            app.GENIST_NLevelsEditField = uieditfield(app.GENIST_OptionalInputsPanel, 'numeric');
            app.GENIST_NLevelsEditField.Limits = [2 Inf];
            app.GENIST_NLevelsEditField.ValueDisplayFormat = '%.0f';
            app.GENIST_NLevelsEditField.Value = 2;
            app.RegBottomPercentageEditFieldLabel = uilabel(app.GENIST_OptionalInputsPanel);
            app.RegBottomPercentageEditFieldLabel.Text = 'Reg Bottom Percentage';
            app.GENIST_RegBottomPercentageEditField = uieditfield(app.GENIST_OptionalInputsPanel, 'numeric');
            app.GENIST_RegBottomPercentageEditField.Limits = [0 1];
            app.GENIST_RegBottomPercentageEditField.Value = 0.2;
            app.GENIST_RequiredInputsPanel = uipanel(app.GENISTTab);
            app.GENIST_RequiredInputsPanel.Title = 'Required Inputs';
            app.GENSIT_GenesFileButton = uibutton(app.GENIST_RequiredInputsPanel, 'push');
            app.GENSIT_GenesFileButton.ButtonPushedFcn = createCallbackFcn(app, @GENSIT_GenesFileButtonPushed, true);
            app.GENSIT_GenesFileButton.Text = 'Choose File';
            app.GENIST_TimeCourseFileButton = uibutton(app.GENIST_RequiredInputsPanel, 'push');
            app.GENIST_TimeCourseFileButton.ButtonPushedFcn = createCallbackFcn(app, @GENIST_TimeCourseFileButtonPushed, true);
            app.GENIST_TimeCourseFileButton.Text = 'Choose File';
            app.GENIST_ClusteringFileButton = uibutton(app.GENIST_RequiredInputsPanel, 'push');
            app.GENIST_ClusteringFileButton.ButtonPushedFcn = createCallbackFcn(app, @GENIST_ClusteringFileButtonPushed, true);
            app.GENIST_ClusteringFileButton.Text = 'Choose File';
            app.TimeCourseFileEditFieldLabel = uilabel(app.GENIST_RequiredInputsPanel);
            app.TimeCourseFileEditFieldLabel.Text = 'Time Course File';
            app.GENIST_TimeCourseFileEditField = uieditfield(app.GENIST_RequiredInputsPanel, 'text');
            app.GenesFileEditFieldLabel = uilabel(app.GENIST_RequiredInputsPanel);
            app.GenesFileEditFieldLabel.Text = 'Genes File';
            app.GENIST_GenesFileEditField = uieditfield(app.GENIST_RequiredInputsPanel, 'text');
            app.ClusteringDataFileEditFieldLabel = uilabel(app.GENIST_RequiredInputsPanel);
            app.ClusteringDataFileEditFieldLabel.Text = 'Clustering Data File';
            app.GENIST_ClusteringFileEditField = uieditfield(app.GENIST_RequiredInputsPanel, 'text');
            app.RegressionTreePipelineTab = uitab(app.TabGroup);
            app.RegressionTreePipelineTab.Title = 'Regression Tree Pipeline';
            app.RTP_InfoTextArea = uitextarea(app.RegressionTreePipelineTab);
            app.RTP_InfoTextArea.Editable = 'off';
            app.RTP_InfoTextArea.Value = {'The Regression Tree Pipeline algorithm infers gene regulatory networks (GRNs) using time course or replicates data. Genes are first clustered, if applicable, and then networks are inferred for each cluster. The final output is a text file that can be uploaded into Cytoscape for visualization.'};
            app.RTP_TimeCourseOptionsPanel = uipanel(app.RegressionTreePipelineTab);
            app.RTP_TimeCourseOptionsPanel.Title = 'Time Course Options';
            app.NumberofTimePointsifapplicableEditFieldLabel = uilabel(app.RTP_TimeCourseOptionsPanel);
            app.NumberofTimePointsifapplicableEditFieldLabel.Text = 'Number of Time Points (if applicable)';
            app.RTP_TimePointsEditField = uieditfield(app.RTP_TimeCourseOptionsPanel, 'numeric');
            app.RTP_TimePointsEditField.ValueDisplayFormat = '%.0f';
            app.RTP_TimePointsEditField.Value = 12;
            app.FoldChangeCutoffifapplicableEditFieldLabel = uilabel(app.RTP_TimeCourseOptionsPanel);
            app.FoldChangeCutoffifapplicableEditFieldLabel.Text = 'Fold Change Cutoff (if applicable)';
            app.RTP_ThresholdEditField = uieditfield(app.RTP_TimeCourseOptionsPanel, 'numeric');
            app.RTP_ThresholdEditField.Value = 1.25;
            app.RTP_UseTimeCourseLabel = uilabel(app.RTP_TimeCourseOptionsPanel);
            app.RTP_UseTimeCourseLabel.Text = 'Use Time Course?';
            app.RTP_UseTimeCourseCheckBox = uicheckbox(app.RTP_TimeCourseOptionsPanel);
            app.RTP_UseTimeCourseCheckBox.Text = '';
            app.RTP_UseTimeCourseCheckBox.Value = true;
            app.RTP_ClusteringOptionsPanel = uipanel(app.RegressionTreePipelineTab);
            app.RTP_ClusteringOptionsPanel.Title = 'Clustering Options';
            app.SetSeedforClusteringLabel = uilabel(app.RTP_ClusteringOptionsPanel);
            app.SetSeedforClusteringLabel.Text = 'Set Seed for Clustering';
            app.RTP_ClusteringSeedEditField = uieditfield(app.RTP_ClusteringOptionsPanel, 'numeric');
            app.MaxNumberofClustersLabel = uilabel(app.RTP_ClusteringOptionsPanel);
            app.MaxNumberofClustersLabel.Text = 'Max Number of Clusters';
            app.RTP_MaxClustersEditField = uieditfield(app.RTP_ClusteringOptionsPanel, 'numeric');
            app.RTP_MaxClustersEditField.ValueDisplayFormat = '%.0f';
            app.RTP_ConnectHubsLabel = uilabel(app.RTP_ClusteringOptionsPanel);
            app.RTP_ConnectHubsLabel.Text = 'Connect Hubs?';
            app.RTP_ConnectHubsCheckBox = uicheckbox(app.RTP_ClusteringOptionsPanel);
            app.RTP_ConnectHubsCheckBox.Text = '';
            app.RTP_ConnectHubsCheckBox.Value = true;
            app.RTP_UseClusteringLabel = uilabel(app.RTP_ClusteringOptionsPanel);
            app.RTP_UseClusteringLabel.Text = 'Use Clustering?';
            app.RTP_UseClusteringCheckBox = uicheckbox(app.RTP_ClusteringOptionsPanel);
            app.RTP_UseClusteringCheckBox.Text = '';
            app.RTP_UseClusteringCheckBox.Value = true;
            app.RTP_NotClustersLabel = uilabel(app.RTP_ClusteringOptionsPanel);
            app.RTP_NotClustersLabel.Text = 'If not providing clusters:';
            app.ClusteringFileNamecsvortxtLabel = uilabel(app.RTP_ClusteringOptionsPanel);
            app.ClusteringFileNamecsvortxtLabel.Text = 'Clustering File Name (.csv or .txt)';
            app.RTP_NotClustersFileNameEditField = uieditfield(app.RTP_ClusteringOptionsPanel, 'text');
            app.RTP_NotClustersFileNameEditField.Value = 'clusters.csv';
            app.RTP_EdgeNumberThresholdsPanel = uipanel(app.RegressionTreePipelineTab);
            app.RTP_EdgeNumberThresholdsPanel.Title = 'Edge Number Thresholds';
            app.LowNumberofTranscriptionFactorsEditFieldLabel = uilabel(app.RTP_EdgeNumberThresholdsPanel);
            app.LowNumberofTranscriptionFactorsEditFieldLabel.Text = 'Low Number of Transcription Factors';
            app.RTP_LowThresholdEditField = uieditfield(app.RTP_EdgeNumberThresholdsPanel, 'numeric');
            app.RTP_LowThresholdEditField.Value = 0.5;
            app.HighNumberofTranscriptionFactorsEditFieldLabel = uilabel(app.RTP_EdgeNumberThresholdsPanel);
            app.HighNumberofTranscriptionFactorsEditFieldLabel.Text = 'High Number of Transcription Factors';
            app.RTP_HighThresholdEditField = uieditfield(app.RTP_EdgeNumberThresholdsPanel, 'numeric');
            app.RTP_HighThresholdEditField.Value = 2;
            app.MediumNumberofTranscriptionFactorsEditFieldLabel = uilabel(app.RTP_EdgeNumberThresholdsPanel);
            app.MediumNumberofTranscriptionFactorsEditFieldLabel.Text = 'Medium Number of Transcription Factors';
            app.RTP_MediumThresholdEditField = uieditfield(app.RTP_EdgeNumberThresholdsPanel, 'numeric');
            app.RTP_MediumThresholdEditField.Value = 1.5;
            app.RTP_InputFilesPanel = uipanel(app.RegressionTreePipelineTab);
            app.RTP_InputFilesPanel.Title = 'Input Files';
            app.RTP_GeneExpressionFileButton = uibutton(app.RTP_InputFilesPanel, 'push');
            app.RTP_GeneExpressionFileButton.ButtonPushedFcn = createCallbackFcn(app, @RTP_GeneExpressionFileButtonButtonPushed, true);
            app.RTP_GeneExpressionFileButton.Text = 'Choose File';
            app.RTP_GeneSymbolsFileButton = uibutton(app.RTP_InputFilesPanel, 'push');
            app.RTP_GeneSymbolsFileButton.ButtonPushedFcn = createCallbackFcn(app, @RTP_GeneSymbolsFileButtonButtonPushed, true);
            app.RTP_GeneSymbolsFileButton.Text = 'Choose File';
            app.RTP_ClusteringFileButton = uibutton(app.RTP_InputFilesPanel, 'push');
            app.RTP_ClusteringFileButton.ButtonPushedFcn = createCallbackFcn(app, @RTP_ClusteringFileButtonButtonPushed, true);
            app.RTP_ClusteringFileButton.Text = 'Choose File';
            app.ResultsFileNameEditFieldLabel = uilabel(app.RTP_InputFilesPanel);
            app.ResultsFileNameEditFieldLabel.Text = 'Results File Name';
            app.RTP_ResultsFileNameEditField = uieditfield(app.RTP_InputFilesPanel, 'text');
            app.RTP_ResultsFileNameEditField.Value = 'biograph.txt';
            app.GeneSymbolsFileEditFieldLabel = uilabel(app.RTP_InputFilesPanel);
            app.GeneSymbolsFileEditFieldLabel.Text = 'Gene Symbols File';
            app.RTP_GeneSymbolsFileEditField = uieditfield(app.RTP_InputFilesPanel, 'text');
            app.GeneExpressionFileEditFieldLabel = uilabel(app.RTP_InputFilesPanel);
            app.GeneExpressionFileEditFieldLabel.Text = 'Gene Expression File';
            app.RTP_GeneExpressionFileEditField = uieditfield(app.RTP_InputFilesPanel, 'text');
            app.ClusteringFileOptionalLabel = uilabel(app.RTP_InputFilesPanel);
            app.ClusteringFileOptionalLabel.Text = 'Clustering File (Optional)';
            app.RTP_ClusteringFileEditField = uieditfield(app.RTP_InputFilesPanel, 'text');
            app.RTP_InputsInfoPanel = uipanel(app.RegressionTreePipelineTab);
            app.RTP_InputsInfoPanel.Title = 'Inputs Info';
            app.RTP_InfoListBox = uilistbox(app.RTP_InputsInfoPanel);
            app.RTP_InfoListBox.Items = {'Gene Expression File', 'Gene Symbols File', 'Clustering File', 'Results File Name', 'Use Time Course', 'Number of Time Points', 'Fold Change Cutoff', 'Use Clustering', 'Uploaded Clustering File', 'Connect Hubs', 'Set Seed for Clustering', 'Max Number of Clusters', 'Clustering File Name', 'Low Number of Transcription Factors', 'Medium Number of Transcription Factors', 'High Number of Transcription Factors'};
            app.RTP_InfoListBox.ItemsData = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15'};
            app.RTP_InfoListBox.ValueChangedFcn = createCallbackFcn(app, @RTP_InfoListBoxValueChanged, true);
            app.RTP_InfoListBox.Value = '0';
            app.RTP_InputsInfoTextArea = uitextarea(app.RTP_InputsInfoPanel);
            app.RTP_InputsInfoTextArea.Editable = 'off';
            app.RTP_InputsInfoTextArea.Value = {'Gene Expression File is the Matlab table that contains the expression data being used for GRN inference.'};
            app.RTP_RunPanel = uipanel(app.RegressionTreePipelineTab);
            app.RTP_RunButton = uibutton(app.RTP_RunPanel, 'push');
            app.RTP_RunButton.ButtonPushedFcn = createCallbackFcn(app, @RTP_RunButtonPushed, true);
            app.RTP_RunButton.Text = 'Run';
            app.RTP_StatusField = uitextarea(app.RTP_RunPanel);
            app.RTP_StatusField.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GENIST_regressiontreepipeline_GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end